classdef newGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SpacialFrequencyDropDownLabel  matlab.ui.control.Label
        SpacialFrequencyDropDown       matlab.ui.control.DropDown
        EnterRotationEditFieldLabel    matlab.ui.control.Label
        EnterRotationEditField         matlab.ui.control.NumericEditField
        SetRotationButton              matlab.ui.control.Button
        GratingSettingsLabel           matlab.ui.control.Label
        GratingSideDropDownLabel       matlab.ui.control.Label
        GratingSideDropDown            matlab.ui.control.DropDown
        SteeringSettingsLabel          matlab.ui.control.Label
        SteeringLengthDropDownLabel    matlab.ui.control.Label
        SteeringLengthDropDown         matlab.ui.control.DropDown
        MovieModeSideDropDownLabel     matlab.ui.control.Label
        MovieModeSideDropDown          matlab.ui.control.DropDown
        SteeringOnOffDropDownLabel     matlab.ui.control.Label
        SteeringOnOffDropDown          matlab.ui.control.DropDown
        EnterSpeedEditFieldLabel       matlab.ui.control.Label
        EnterSpeedEditField            matlab.ui.control.NumericEditField
        SetSpeedButton                 matlab.ui.control.Button
        BranchNumberDropDownLabel      matlab.ui.control.Label
        BranchNumberDropDown           matlab.ui.control.DropDown
        BranchSettingsLabel            matlab.ui.control.Label
        GeneralSettingsLabel           matlab.ui.control.Label
        LogTextEditFieldLabel          matlab.ui.control.Label
        LogTextEditField               matlab.ui.control.EditField
        LogButton                      matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        debugEditFieldLabel            matlab.ui.control.Label
        debugEditField                 matlab.ui.control.EditField
    end


    properties (Access = private)
        LinearMazeObject  % handle for LinearMaze class
    end


    methods (Access = private)

        % Code that executes after component creation
        function CallLinearMaze(app)
            app.LinearMazeObject = LinearMaze();%initialize linearMaze
            app.LinearMazeObject.RecieveAppHandle(app);%send a handle to this app
            
        end

        % Value changed function: SpacialFrequencyDropDown
        function SendtoLinearMaze_SpatFreq(app, event)
            %value = app.SpacialFrequencyDropDown.Value;
            value = find(strcmp(app.SpacialFrequencyDropDown.Items,app.SpacialFrequencyDropDown.Value)); %find index of value currently selected
            
            app.LinearMazeObject.stimThickness(value) %send change of spatial freq to linearMaze
            
        end

        % Button pushed function: SetRotationButton
        function SendtoLinearMaze_rotation(app, event)
            %get from rotation textbox
            %send value to textRotation
            rotation = app.EnterRotationEditField.Value;
            app.LinearMazeObject.textRotation(rotation);
        end

        % Button pushed function: StartButton
        function SendtoLinearMaze_start(app, event)
             app.LinearMazeObject.start();
        end

        % Button pushed function: StopButton
        function SendtoLinearMaze_stop(app, event)
            app.LinearMazeObject.stop();
        end

        % Button pushed function: ResetButton
        function SendtoLinearMaze_reset(app, event)
            app.LinearMazeObject.reset();
        end

        % Value changed function: GratingSideDropDown
        function SendtoLinearMaze_GratingSide(app, event)
            %(1random, 2left, 3right, 4off)
            sideNum = find(strcmp(app.GratingSideDropDown.Items,app.GratingSideDropDown.Value)); %find index of value currently selected
            
            %app.GratingSideDropDown.Value;
            app.LinearMazeObject.ManualGratingSide(sideNum);
        end

        % Button pushed function: SetSpeedButton
        function SendtoLinearMaze_speed(app, event)
            speed = app.EnterSpeedEditField.Value;
            app.LinearMazeObject.textSpeed(speed);
        end

        % Value changed function: MovieModeSideDropDown
        function SendtoLinearMaze_MovieSteering(app, event)
            sideNum = find(strcmp(app.MovieModeSideDropDown.Items,app.MovieModeSideDropDown.Value)); %find index of value currently selected
            app.LinearMazeObject.ManualDirection(sideNum);
            
        end

        % Value changed function: BranchNumberDropDown
        function SendtoLinearMaze_BranchNum(app, event)
            
            branchNum = find(strcmp(app.BranchNumberDropDown.Items,app.BranchNumberDropDown.Value)); %find index of value currently selected
            app.LinearMazeObject.chooseBranch(branchNum);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SpacialFrequencyDropDownLabel = uilabel(app.UIFigure);
            app.SpacialFrequencyDropDownLabel.Text = 'Spacial Frequency';
            app.SpacialFrequencyDropDown = uidropdown(app.UIFigure);
            app.SpacialFrequencyDropDown.Items = {'Thick', 'Thin'};
            app.SpacialFrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @SendtoLinearMaze_SpatFreq, true);
            app.SpacialFrequencyDropDown.Value = 'Thick';
            app.EnterRotationEditFieldLabel = uilabel(app.UIFigure);
            app.EnterRotationEditFieldLabel.Text = 'Enter Rotation:';
            app.EnterRotationEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetRotationButton = uibutton(app.UIFigure, 'push');
            app.SetRotationButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoLinearMaze_rotation, true);
            app.SetRotationButton.Text = 'Set Rotation';
            app.GratingSettingsLabel = uilabel(app.UIFigure);
            app.GratingSettingsLabel.Text = 'Grating Settings:';
            app.GratingSideDropDownLabel = uilabel(app.UIFigure);
            app.GratingSideDropDownLabel.Text = 'Grating Side:';
            app.GratingSideDropDown = uidropdown(app.UIFigure);
            app.GratingSideDropDown.Items = {'Random', 'Left', 'Right', 'Off'};
            app.GratingSideDropDown.ValueChangedFcn = createCallbackFcn(app, @SendtoLinearMaze_GratingSide, true);
            app.GratingSideDropDown.Value = 'Random';
            app.SteeringSettingsLabel = uilabel(app.UIFigure);
            app.SteeringSettingsLabel.Text = 'Steering Settings:';
            app.SteeringLengthDropDownLabel = uilabel(app.UIFigure);
            app.SteeringLengthDropDownLabel.Text = 'Steering Length';
            app.SteeringLengthDropDown = uidropdown(app.UIFigure);
            app.SteeringLengthDropDown.Items = {'1/4', '2/4', '3/4', '4/4'};
            app.SteeringLengthDropDown.Value = '1/4';
            app.MovieModeSideDropDownLabel = uilabel(app.UIFigure);
            app.MovieModeSideDropDownLabel.Text = 'MovieMode Side';
            app.MovieModeSideDropDown = uidropdown(app.UIFigure);
            app.MovieModeSideDropDown.Items = {'Random', 'Left', 'Right'};
            app.MovieModeSideDropDown.ValueChangedFcn = createCallbackFcn(app, @SendtoLinearMaze_MovieSteering, true);
            app.MovieModeSideDropDown.Value = 'Random';
            app.SteeringOnOffDropDownLabel = uilabel(app.UIFigure);
            app.SteeringOnOffDropDownLabel.Text = 'Steering On/Off';
            app.SteeringOnOffDropDown = uidropdown(app.UIFigure);
            app.SteeringOnOffDropDown.Items = {'On (doesnt work)', 'Temp MovieMode'};
            app.SteeringOnOffDropDown.Value = 'On (doesnt work)';
            app.EnterSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.EnterSpeedEditFieldLabel.Text = 'Enter Speed:';
            app.EnterSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterSpeedEditField.Value = 25;
            app.SetSpeedButton = uibutton(app.UIFigure, 'push');
            app.SetSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoLinearMaze_speed, true);
            app.SetSpeedButton.Text = 'Set Speed';
            app.BranchNumberDropDownLabel = uilabel(app.UIFigure);
            app.BranchNumberDropDownLabel.Text = 'Branch Number';
            app.BranchNumberDropDown = uidropdown(app.UIFigure);
            app.BranchNumberDropDown.Items = {'1', '2', '3'};
            app.BranchNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @SendtoLinearMaze_BranchNum, true);
            app.BranchNumberDropDown.Value = '1';
            app.BranchSettingsLabel = uilabel(app.UIFigure);
            app.BranchSettingsLabel.Text = 'Branch Settings:';
            app.GeneralSettingsLabel = uilabel(app.UIFigure);
            app.GeneralSettingsLabel.Text = 'General Settings:';
            app.LogTextEditFieldLabel = uilabel(app.UIFigure);
            app.LogTextEditFieldLabel.Text = 'Log Text';
            app.LogTextEditField = uieditfield(app.UIFigure, 'text');
            app.LogButton = uibutton(app.UIFigure, 'push');
            app.LogButton.Text = 'Log Button';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoLinearMaze_reset, true);
            app.ResetButton.Text = 'Reset';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoLinearMaze_start, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoLinearMaze_stop, true);
            app.StopButton.Text = 'Stop';
            app.debugEditFieldLabel = uilabel(app.UIFigure);
            app.debugEditFieldLabel.Text = 'debug';
            app.debugEditField = uieditfield(app.UIFigure, 'text');
            app.debugEditField.Value = 'poopsnoot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = newGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @CallLinearMaze)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end